classdef cellapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditField_9               matlab.ui.control.NumericEditField
        Panel                     matlab.ui.container.Panel
        Label_19                  matlab.ui.control.Label
        Label_18                  matlab.ui.control.Label
        Label_17                  matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        Label_14                  matlab.ui.control.Label
        EditField_8               matlab.ui.control.NumericEditField
        ObjectSize                matlab.ui.control.RangeSlider
        Label_13                  matlab.ui.control.Label
        Label_11                  matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        TrackingOption            matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        EditField_7               matlab.ui.control.NumericEditField
        LevelEditFieldLabel_8     matlab.ui.control.Label
        CloseButton               matlab.ui.control.Button
        LevelEditField_2          matlab.ui.control.NumericEditField
        MinIOUTrack               matlab.ui.control.Slider
        EditField_6               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        Alpha                     matlab.ui.control.Slider
        LevelEditFieldLabel_7     matlab.ui.control.Label
        LevelEditFieldLabel_6     matlab.ui.control.Label
        TrackingLabel_2           matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        MaxProposals              matlab.ui.control.NumericEditField
        LevelEditFieldLabel_5     matlab.ui.control.Label
        OverlapProp_t             matlab.ui.control.NumericEditField
        OverlaPP_t                matlab.ui.control.NumericEditField
        LevelEditFieldLabel_4     matlab.ui.control.Label
        LevelEditFieldLabel_3     matlab.ui.control.Label
        OverlapProp               matlab.ui.control.Slider
        OverlaPP                  matlab.ui.control.Slider
        DropDown                  matlab.ui.control.DropDown
        SegmentationNetworkLabel  matlab.ui.control.Label
        LevelEditFieldLabel_2     matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        LevelEditField            matlab.ui.control.NumericEditField
        LevelEditFieldLabel       matlab.ui.control.Label
        PreProcessingLabel        matlab.ui.control.Label
        AdvancedLabel             matlab.ui.control.Label
        ConfidenceField           matlab.ui.control.NumericEditField
        Confidence                matlab.ui.control.Slider
        ConfidenceThresholdLabel  matlab.ui.control.Label
        RelaxedButton             matlab.ui.control.Button
        ConservativeButton        matlab.ui.control.Button
        DefaultButton             matlab.ui.control.Button
        PresetLabel               matlab.ui.control.Label
        SettingsLabel             matlab.ui.control.Label
        InitialLabel              matlab.ui.control.Label
        YFeatureLabel_2           matlab.ui.control.Label
        XFeatureLabel_2           matlab.ui.control.Label
        PauseButton               matlab.ui.control.Button
        PlotFeaturesText          matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        ModeSwitch                matlab.ui.control.Switch
        ImageDisp                 matlab.ui.control.Image
        ExportData_Button         matlab.ui.control.Button
        Credit                    matlab.ui.control.Hyperlink
        XDropDown                 matlab.ui.control.DropDown
        YDropDown                 matlab.ui.control.DropDown
        Number_of_Cells_Field     matlab.ui.control.NumericEditField
        Number_of_Cells_Text      matlab.ui.control.Label
        Segment_Button            matlab.ui.control.Button
        Help_Button               matlab.ui.control.Button
        Settings_Button           matlab.ui.control.Button
        UploadImagesButton        matlab.ui.control.Button
        ProgressBarAxes           matlab.ui.control.UIAxes
        HistogramAxes             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    CurrentImageIndex double = 1;
    SegmentedImages = []; % Stores the segmented image
    net = []; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    SingleMasks = [];
    SingleBoxes = [];
    SingleScores = [];
    SingleLabels = [];
    AnalysisData = [];
        Progress = 0; 
        TrackingInfo table = [];
        SingleImage logical = 0; 
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")

        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
       
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end

       
        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.YDropDown.Value, 'Frequency')
                % Histogram view
                featureNameX = app.XDropDown.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
            else
                % Scatter plot view
                featureX = app.XDropDown.Value;
                featureY = app.YDropDown.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
            end
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
        end

      
        function UpdateProgress(app)
            barh(app.Progress, 'Parent',app.ProgressBarAxes,'EdgeColor','none','FaceColor',[0.4660 0.6740 0.1880], "BarWidth" ,23000)
            
            if app.Progress>0
                text(0.475,0,[num2str(app.Progress*100,'%.0f')+"%"],'Parent',app.ProgressBarAxes,'FontSize',20)
            end
            drawnow
        end

        function highlightButton(app, selectedButton)
    allButtons = [app.DefaultButton, app.ConservativeButton, app.RelaxedButton];

    for btn = allButtons
        btn.BackgroundColor = [0.76 0.80 0.80];  
        btn.FontColor = [0 0 0];                
    end

    selectedButton.BackgroundColor = [0.05 0.30 0.30];  % Blue highlight
    selectedButton.FontColor = [1 1 1];                % White text
end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
            addpath(genpath('MRCNNsrc'));
            %change to be efficeintnet as default later, for dev purposes
            app.net = load('ResNet50.mat'); % Load the network file 
            app.net = app.net.net; % Extract the network object
            set(app.ProgressBarAxes,'visible','off');
            set(app.ProgressBarAxes,'xtick',[]);
            UpdateProgress(app);
            set(app.ProgressBarAxes, 'XLim', [0 1]);
            DefaultButtonPushed(app);
        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            app.InitialLabel.Visible = 'off';
            
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
           % if isequal(files, 0)
           %     errordlg('No images selected!', 'Error');
           %     return;
           % end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));

                [img, ~, ~] = resizeImageandMask(img, [],[], [528, 704]);

                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);
            
            app.Segment_Button.Visible='on';
        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 
        
        %app.Segment_Button.Visible='off';
        app.Number_of_Cells_Field.Visible = 'on';
        app.Number_of_Cells_Text.Visible = 'on';
        app.PauseButton.Visible = 'on';
       
        app.net.OverlapThresholdPrediction = app.OverlaPP.Value / 100;
        app.net.OverlapThresholdRPN = app.OverlapProp.Value / 100;
        
    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end

    uiresume(app.UIFigure);

    % Perform cell segmentation
    try
        if ~class(app.Images)=='cell'
            app.Images{1} = app.Images; %makes sure all images have same format: cell, containing each image
            % move this logic to images being loaded later 
        end


        TrackingData = [];
        
        for i=[1:size(app.Images,2)] %for each image loaded in
        
         app.Progress = ((i-1)+ 0.05) / size(app.Images,2);
         UpdateProgress(app);
         if size(app.Images,2)==1
             app.SingleImage = 1;
             [app.SingleMasks app.SingleLabels app.SingleScores app.SingleBoxes] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",Inf, MinSize=[2 2], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=1e-7);
             
             
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);

         elseif app.TrackingOption.Value == 1
             app.SingleImage = 0;
            [app.Masks{i} app.Labels{i} app.Scores{i} app.Boxes{i} TrackingData] =  segmentFrame(net, im(:,:,2), TrackingData, "NumStrongestRegions",Inf, Threshold=app.Confidence.Value,Alpha=app.Alpha.Value);
            [tracks, trackingTable] = createTracks(tracks, app.Boxes{i}, app.Scores{i}, i, trackingTable, "MinIoU",app.MinIOUTrack,"MaxInvisibleCount",5);
         else
            app.SingleImage = 0;
            [app.Masks{i} app.Labels{i} app.Scores{i} app.Boxes{i}] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",Inf, MinSize=[2 2], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=app.Confidence.Value);
         end 
         app.Progress = ((i-1)+ 0.9) / size(app.Images,2);
         UpdateProgress(app);
       

         DispColors = 1.4 - 0.5*app.Scores{i};
         DispColors = DispColors .* [0.466 0.674	0.188];
            
        if isempty(app.Masks{i})
            app.SegmentedImages = app.Images{i};
        else
            app.SegmentedImages = insertObjectMask(app.Images{i}, app.Masks{i}, Color=DispColors );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
        
        app.AnalysisData{i} = ExtractFeatures(app.Images{i}, app.Masks{i}, app.Boxes{i}, app.Scores{i}, 1);
        
       % app.AnalysisData{1}.Area

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData{i}, 1);
        %app.Area_Field.Value = mean(app.AnalysisData.Area);
        app.Progress = i / size(app.Images,2);
        UpdateProgress(app);
       
        
        end
        if app.TrackingOption.Value == 1 
        app.TrackingInfo = TrackingTable;
        end

        app.PauseButton.Visible = 'off';
        app.ExportData_Button.Visible = 'on';
      
        app.PlotFeaturesText.Visible = 'on';
        app.ModeSwitch.Visible = 'on';
        app.NextButton.Visible = 'on';
        app.PreviousButton.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.HistogramAxes.Visible = 'on';
        app.XFeatureLabel_2.Visible = 'on';
        app.YFeatureLabel_2.Visible = 'on';

        PlotHistogram(app)


    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end


        end

        % Value changed function: XDropDown, YDropDown
        function PlotHistogram(app, event)
            xFeature = app.XDropDown.Value;
            yFeature = app.YDropDown.Value;

            if isempty(app.AnalysisData)
                return;
            end

            % Extract the selected feature's values
            featureData = app.AnalysisData{1};

            % Decide plot type
            if  strcmp(yFeature, 'Frequency')
                
                % Plot histogram
                histogram(app.HistogramAxes,featureData.(xFeature));
                xlabel(app.HistogramAxes, xFeature);
                ylabel(app.HistogramAxes, 'Frequency');
            else
                % Scatter plot view
                xValues = featureData.(xFeature);
                yValues = featureData.(yFeature);

        % Create scatter plot
        scatter(app.HistogramAxes, xValues, yValues, 'filled');
        xlabel(app.HistogramAxes, xFeature);
        ylabel(app.HistogramAxes, yFeature);


            end
        end

        % Callback function
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
              end
        
        end

        % Callback function
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
           end
        
        end

        % Button pushed function: PreviousButton
        function ToggleSwitchValueChanged(app, event)

        end

        % Callback function
        function XAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function YAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Button pushed function: Settings_Button
        function Settings_ButtonPushed(app, event)
    if strcmp(app.Panel.Visible, 'on')
        app.Panel.Visible = 'off';
    else
        app.Panel.Visible = 'on';
        app.EditField_9.Visible = 'off';
    end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Panel.Visible = 'off';

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if strcmp(app.Switch_2.Value, 'Off')
                app.LevelEditField.Visible = "off";
                app.LevelEditField_2.Visible = "off";
                app.LevelEditFieldLabel.Visible = "off";
                app.LevelEditFieldLabel_2.Visible = "off";
                app.Slider_2.Visible = 'off';
                app.Label_19.Visible = 'off';
            else
                app.LevelEditField.Visible = "on";
                app.LevelEditField_2.Visible = "on";
                app.LevelEditFieldLabel.Visible = "on";
                app.LevelEditFieldLabel_2.Visible = "on";
                app.Slider_2.Visible = 'on';
                app.Label_19.Visible = 'on';

            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
         
        end

        % Value changed function: TrackingOption
        function TrackingOptionValueChanged2(app, event)
             if strcmp(app.TrackingOption.Value, 'Off')
                app.MinIOUTrack.Visible = 'off';
                app.EditField_6.Visible = "off";
                app.EditField_5.Visible = 'off';
                app.Alpha.Visible = 'off';
                app.LevelEditFieldLabel_6.Visible = 'off';
                app.LevelEditFieldLabel_7.Visible = 'off';
                app.Label_11.Visible = 'off';
                app.Label_13.Visible = 'off';
            else
                app.MinIOUTrack.Visible = 'on';
                app.EditField_6.Visible = "on";
                app.EditField_5.Visible = 'on';
                app.Alpha.Visible = 'on';
                app.LevelEditFieldLabel_6.Visible = 'on';
                app.LevelEditFieldLabel_7.Visible = 'on';
                app.Label_11.Visible = 'on';
                app.Label_13.Visible = 'on';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.PauseButton.Visible = 'off';
            uiwait(app.UIFigure);

        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
       highlightButton(app, app.DefaultButton);
       app.OverlaPP.Value = 30;
       app.OverlapProp.Value = 30;
       app.Confidence.Value = 50;
        end

        % Button pushed function: ConservativeButton
        function ConservativeButtonPushed(app, event)
        highlightButton(app, app.ConservativeButton);
        
        end

        % Button pushed function: RelaxedButton
        function RelaxedButtonPushed(app, event)
       highlightButton(app, app.RelaxedButton);
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            
    if strcmp(value, 'All')
        % Show the Previous and Next buttons
        app.PreviousButton.Visible = 'on';
        app.NextButton.Visible = 'on';
        app.EditField_9.Visible = 'on';
    else
        % Hide the Previous and Next buttons
        app.PreviousButton.Visible = 'off';
        app.NextButton.Visible = 'off';
        app.EditField_9.Visible = 'on';
    end
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
 % to fix
        end

        % Value changed function: OverlaPP
        function OverlaPPValueChanged(app, event)
            value = app.OverlaPP.Value;
            if value>=100
                value = 100
            elseif value <0
                value = 1e-7
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: OverlapProp_t
        function OverlapProp_tValueChanged(app, event)
            value = app.OverlapProp_t.Value;
            if value>=100
                value = 100
            elseif value <0
                value = 1e-7
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: OverlapProp
        function OverlapPropValueChanged(app, event)
            value = app.OverlapProp.Value;
            if value>=100
                value = 100
            elseif value <0
                value = 1e-7
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: OverlaPP_t
        function OverlaPP_tValueChanged(app, event)
            value = app.OverlaPP_t.Value;
            if value>=100
                value = 100
            elseif value <0
                value = 1e-7
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: Confidence
        function ConfidenceValueChanged(app, event)
            value = app.Confidence.Value;
            if value>=100
                value = 100
            elseif value <0
                value = 1e-7
            end
            app.Confidence.Value = value;
            app.ConfidenceField.Value = value;
                
            if app.SingleImage ==1
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);
             app.AnalysisData{1} = ExtractFeatures(app.Images{1}, app.Masks{1}, app.Boxes{1}, app.Scores{1}, 1);

            DispColors = 1.4 - 0.5*app.Scores{1};
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            end


        end

        % Value changed function: ConfidenceField
        function ConfidenceFieldValueChanged(app, event)
            value = app.ConfidenceField.Value;
            if value>=100
                value = 100
            elseif value <=0
                value = 1e-7
            end
            app.Confidence.Value = value;
            app.ConfidenceField.Value = value;

            if app.SingleImage ==1
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);
             app.AnalysisData{1} = ExtractFeatures(app.Images{1}, app.Masks{1}, app.Boxes{1}, app.Scores{1}, 1);
            DispColors = 1.4 - 0.5*app.Scores{1};
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            end


        end

        % Value changing function: Confidence
        function ConfidenceValueChanging(app, event)
            changingValue = event.Value;
            if changingValue>=100
                changingValue = 100
            elseif changingValue <0
                changingValue = 0
            end
            app.Confidence.Value = changingValue;
            app.ConfidenceField.Value = changingValue;

            if app.SingleImage ==1
            app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
            app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);

            DispColors = 1.4 - 0.5*app.Scores{1};
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            end

           
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;
            
        end

        % Value changed function: LevelEditField_2
        function LevelEditField_2ValueChanged(app, event)
            value = app.LevelEditField_2.Value;
            % link with slider (app.Slider_2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            app.HistogramAxes.Visible = 'off';
            colormap(app.HistogramAxes, 'turbo')
            app.ProgressBarAxes = uiaxes(app.UIFigure);
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.ButtonPushedFcn = createCallbackFcn(app, @Settings_ButtonPushed, true);
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'settings.png');
            app.Settings_Button.Tooltip = {'Settings'};
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Tooltip = {'Help (Opens User Guide)'};
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Visible = 'off';
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Visible = 'off';
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Cells_Field.Limits = [0 Inf];
            app.Number_of_Cells_Field.RoundFractionalValues = 'on';
            app.Number_of_Cells_Field.AllowEmpty = 'on';
            app.Number_of_Cells_Field.Editable = 'off';
            app.Number_of_Cells_Field.Visible = 'off';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'STDeviation', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.YDropDown.Visible = 'off';
            app.YDropDown.Value = 'Perimeter';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'STDeviation', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Visible = 'off';
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = {'GitHub'; ''};
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export.png');
            app.ExportData_Button.Visible = 'off';
            app.ExportData_Button.Tooltip = {'Export Data'};
            app.ExportData_Button.Text = '';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All', 'Individual'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Visible = 'off';
            app.ModeSwitch.Tooltip = {'Plot values for a single image or across the entire dataset'};
            app.ModeSwitch.Value = 'All';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'previousbutton.png');
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Tooltip = {'Previous Image'};
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Icon = fullfile(pathToMLAPP, 'nextbutton.png');
            app.NextButton.Visible = 'off';
            app.NextButton.Tooltip = {'Next Image'};
            app.NextButton.Text = '';
            app.PlotFeaturesText = uilabel(app.UIFigure);
            app.PlotFeaturesText.Visible = 'off';
            app.PlotFeaturesText.Text = 'Plot Features:';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Tooltip = {'Cancel Current Segmentation'};
            app.PauseButton.Text = 'Cancel';
            app.XFeatureLabel_2 = uilabel(app.UIFigure);
            app.XFeatureLabel_2.Visible = 'off';
            app.XFeatureLabel_2.Text = 'X ';
            app.YFeatureLabel_2 = uilabel(app.UIFigure);
            app.YFeatureLabel_2.Visible = 'off';
            app.YFeatureLabel_2.Text = 'Y';
            app.InitialLabel = uilabel(app.UIFigure);
            app.InitialLabel.Text = 'Please select images to start.';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.SettingsLabel = uilabel(app.Panel);
            app.SettingsLabel.Text = 'Settings';
            app.PresetLabel = uilabel(app.Panel);
            app.PresetLabel.Tooltip = {'Presets mainly affect the false positive and negative rates'};
            app.PresetLabel.Text = 'Preset';
            app.DefaultButton = uibutton(app.Panel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ConservativeButton = uibutton(app.Panel, 'push');
            app.ConservativeButton.ButtonPushedFcn = createCallbackFcn(app, @ConservativeButtonPushed, true);
            app.ConservativeButton.Text = 'Conservative';
            app.RelaxedButton = uibutton(app.Panel, 'push');
            app.RelaxedButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxedButtonPushed, true);
            app.RelaxedButton.Text = 'Relaxed';
            app.ConfidenceThresholdLabel = uilabel(app.Panel);
            app.ConfidenceThresholdLabel.Tooltip = {'The minimum confidence to display a result'};
            app.ConfidenceThresholdLabel.Text = 'Confidence Threshold';
            app.Confidence = uislider(app.Panel);
            app.Confidence.MajorTicks = [0 100];
            app.Confidence.ValueChangedFcn = createCallbackFcn(app, @ConfidenceValueChanged, true);
            app.Confidence.ValueChangingFcn = createCallbackFcn(app, @ConfidenceValueChanging, true);
            app.ConfidenceField = uieditfield(app.Panel, 'numeric');
            app.ConfidenceField.Limits = [0 100];
            app.ConfidenceField.ValueDisplayFormat = '%5.0f';
            app.ConfidenceField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceFieldValueChanged, true);
            app.ConfidenceField.Placeholder = '20%';
            app.ConfidenceField.Value = 50;
            app.AdvancedLabel = uilabel(app.Panel);
            app.AdvancedLabel.Text = 'Advanced ';
            app.PreProcessingLabel = uilabel(app.Panel);
            app.PreProcessingLabel.Text = 'Pre-Processing';
            app.LevelEditFieldLabel = uilabel(app.Panel);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.Panel, 'numeric');
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Value = 4;
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.Limits = [0 5];
            app.Slider_2.MajorTicks = [0 5];
            app.Slider_2.Tooltip = {'Tune agressiveness of de-noising'};
            app.LevelEditFieldLabel_2 = uilabel(app.Panel);
            app.LevelEditFieldLabel_2.Text = 'Threshold';
            app.SegmentationNetworkLabel = uilabel(app.Panel);
            app.SegmentationNetworkLabel.Text = 'Segmentation Network';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'EfficientNet', 'ResNet50', 'ResNet101'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'EfficientNet';
            app.OverlaPP = uislider(app.Panel);
            app.OverlaPP.MajorTicks = [0 100];
            app.OverlaPP.ValueChangedFcn = createCallbackFcn(app, @OverlaPPValueChanged, true);
            app.OverlapProp = uislider(app.Panel);
            app.OverlapProp.MajorTicks = [0 100];
            app.OverlapProp.ValueChangedFcn = createCallbackFcn(app, @OverlapPropValueChanged, true);
            app.LevelEditFieldLabel_3 = uilabel(app.Panel);
            app.LevelEditFieldLabel_3.Tooltip = {'Filter results that overlap above this value'};
            app.LevelEditFieldLabel_3.Text = 'Overlap';
            app.LevelEditFieldLabel_4 = uilabel(app.Panel);
            app.LevelEditFieldLabel_4.Tooltip = {'Filter proposals that overlap'};
            app.LevelEditFieldLabel_4.Text = {'Proposal'; 'Overlap'};
            app.OverlaPP_t = uieditfield(app.Panel, 'numeric');
            app.OverlaPP_t.ValueChangedFcn = createCallbackFcn(app, @OverlaPP_tValueChanged, true);
            app.OverlaPP_t.Value = 30;
            app.OverlapProp_t = uieditfield(app.Panel, 'numeric');
            app.OverlapProp_t.ValueChangedFcn = createCallbackFcn(app, @OverlapProp_tValueChanged, true);
            app.OverlapProp_t.Value = 30;
            app.LevelEditFieldLabel_5 = uilabel(app.Panel);
            app.LevelEditFieldLabel_5.Text = 'Maximum Proposals';
            app.MaxProposals = uieditfield(app.Panel, 'numeric');
            app.MaxProposals.Tooltip = {'Number of potential cells evaluated, increases accuracy, increases processing time'};
            app.MaxProposals.Value = 5000;
            app.Switch_2 = uiswitch(app.Panel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'Enable de-noising of images (recommended)'};
            app.TrackingLabel_2 = uilabel(app.Panel);
            app.TrackingLabel_2.Text = 'Tracking';
            app.LevelEditFieldLabel_6 = uilabel(app.Panel);
            app.LevelEditFieldLabel_6.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.LevelEditFieldLabel_6.Text = 'Min Overlap';
            app.LevelEditFieldLabel_7 = uilabel(app.Panel);
            app.LevelEditFieldLabel_7.Tooltip = {'Speed up processing during tracking by pruning proposals'};
            app.LevelEditFieldLabel_7.Text = {'RPN '; 'Optimization '; 'Factor'};
            app.Alpha = uislider(app.Panel);
            app.Alpha.Limits = [0 20];
            app.Alpha.MajorTicks = [0 20];
            app.Alpha.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.EditField_5.Value = 15;
            app.EditField_6 = uieditfield(app.Panel, 'numeric');
            app.EditField_6.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.EditField_6.Value = 5;
            app.MinIOUTrack = uislider(app.Panel);
            app.MinIOUTrack.Limits = [0 20];
            app.MinIOUTrack.MajorTicks = [0 20];
            app.MinIOUTrack.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.LevelEditField_2 = uieditfield(app.Panel, 'numeric');
            app.LevelEditField_2.ValueChangedFcn = createCallbackFcn(app, @LevelEditField_2ValueChanged, true);
            app.LevelEditField_2.Value = 2;
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.CloseButton.Text = '';
            app.LevelEditFieldLabel_8 = uilabel(app.Panel);
            app.LevelEditFieldLabel_8.Text = 'Cell Size';
            app.EditField_7 = uieditfield(app.Panel, 'numeric');
            app.EditField_7.Tooltip = {'Filter cells above/below a certain size'};
            app.EditField_7.Value = 4;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '________________________________________________________________________________________________________________________';
            app.TrackingOption = uiswitch(app.Panel, 'slider');
            app.TrackingOption.ValueChangedFcn = createCallbackFcn(app, @TrackingOptionValueChanged2, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '____________________________________________________________________________________________________';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '____________________________________________________________________________________________________';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '%';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '%';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '%';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '%';
            app.ObjectSize = uislider(app.Panel, 'range');
            app.ObjectSize.Limits = [0 250];
            app.ObjectSize.MajorTicks = [0 250];
            app.ObjectSize.Value = [0 250];
            app.EditField_8 = uieditfield(app.Panel, 'numeric');
            app.EditField_8.Value = 160;
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '________________________________________________________________________________________________________________________';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '________________________________________________________________________________________________________________________';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '________________________________________________________________________________________________________________________';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = '________________________________________________________________________________________________________________________';
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Text = '________________________________________________________________________________________________________________________';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = '%';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%11.0g';
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end